classdef MotionTrackingDisplay_ITCL_v04 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        SaveButton            matlab.ui.control.Button
        DisplayTImestepLabel  matlab.ui.control.Label
        TimestepSlider        matlab.ui.control.Slider
        TimestepSliderLabel   matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        positions     % Cell array to store 3D data for multiple objects
        handles       % Array of plot handles for each object
        objectNames   % Cell array of object names
        numObjects    % Number of objects being tracked
        numTimesteps  % Number of timesteps (number of .mat files loaded)
        dataDir       % Directory for input data
        isPOI         % logical array which object is POI %delete if not working
    end
    
    % Function to load data and initialize the plot
    methods (Access = private)

        function loadData(app)
            % Scan the directory for .mat files
            files = dir(fullfile(app.dataDir, '*.mat'));
            fileNames = {files.name};
            
            if isempty(fileNames)
                uialert(app.UIFigure, 'No .mat files found in the directory.', 'Error');
                return;
            end
        
            % Create a progress dialog
            progressDlg = uiprogressdlg(app.UIFigure, ...
                'Title', 'Loading Data', ...
                'Message', 'Initializing...', ...
                'Cancelable', 'on');
        
            % Detect unique object names from filenames
            app.objectNames = unique(regexprep(fileNames, '_\d{4}\.mat$', '')); % Remove timestep
            app.numObjects = numel(app.objectNames);
        
            % Preallocate storage
            app.positions = cell(1, app.numObjects);
        
            try
                % Group files by object
                for i = 1:app.numObjects
                    % Update progress
                    progressDlg.Message = sprintf('Loading data for object %d of %d...', i, app.numObjects);
                    progressDlg.Value = i / app.numObjects;
        
                    % Allow user to cancel
                    if progressDlg.CancelRequested
                        error('Data loading canceled by user.');
                    end
        
                    % Find files for this object
                    pattern = sprintf('%s_\\d{4}\\.mat', app.objectNames{i});
                    objectFiles = regexp(fileNames, pattern, 'match', 'once');
                    objectFiles = objectFiles(~cellfun('isempty', objectFiles)); % Remove empty matches
        
                    if isempty(objectFiles)
                        continue; % Skip if no files found for the object
                    end
        
                    % Load files in sorted order by timestep
                    timesteps = cellfun(@(name) sscanf(name, [app.objectNames{i} '_%d.mat']), objectFiles);
                    [~, sortIdx] = sort(timesteps);
                    objectFiles = objectFiles(sortIdx);
        
                    % Load positions for the object
                    positions = cell(1, numel(objectFiles));
                    for j = 1:numel(objectFiles)
                        loadedData = load(fullfile(app.dataDir, objectFiles{j}));
                        varNames = fieldnames(loadedData);
                        positions{j} = loadedData.(varNames{1});
                    end
                    app.positions{i} = positions;
                end
        
                % Set the number of timesteps based on the first object
                app.numTimesteps = numel(app.positions{1});
            catch ME
                % Handle errors gracefully
                uialert(app.UIFigure, sprintf('Error during data loading:\n%s', ME.message), 'Error');
            end
        
            % Close progress dialog
            close(progressDlg);
        end
        
        function [xLimits, yLimits, zLimits] = computeAxisLimits(app)
            % Combine all data across objects and timesteps
            allData = [];
            for i = 1:app.numObjects
                for t = 1:app.numTimesteps
                    if ~isempty(app.positions{i}{t})
                        allData = [allData, app.positions{i}{t}]; %#ok<AGROW> (expand array)
                    end
                end
            end
        
            % Compute global limits
            xLimits = [min(allData(1, :)), max(allData(1, :))];
            yLimits = [min(allData(2, :)), max(allData(2, :))];
            zLimits = [min(allData(3, :)), max(allData(3, :))];
        end

        function initializePlot(app)
            app.handles = gobjects(1, app.numObjects);  % Initialize plot handles array
            hold(app.UIAxes, 'on');  % Hold the current plot

            % Distinct colors for all objects (except POIs which stay red)
            cmap = lines(app.numObjects);              % or parula/app.numObjects, turbo, hsv, etc.

            for i = 1:app.numObjects
                if ~isempty(app.positions{i}{1})
                    P = app.positions{i}{1};

                    if contains(app.objectNames{i}, '_PoI')     % --- Point of Interest
                        app.handles(i) = plot3(app.UIAxes, ...
                            P(1,:), P(2,:), P(3,:), ...
                            'o', 'MarkerSize', 24, ...
                            'MarkerFaceColor', 'r', 'MarkerEdgeColor', 'k', ...
                            'DisplayName', app.objectNames{i});
                    else
                        % unique color for every object
                        app.handles(i) = plot3(app.UIAxes, ...
                            P(1,:), P(2,:), P(3,:), ...
                            '.', 'MarkerSize', 6, ...
                            'Color', cmap(i,:), ...
                            'DisplayName', app.objectNames{i});
                    end
                end
            end
            % Plot each object at the first timestep
            % for i = 1:app.numObjects
            %     if ~isempty(app.positions{i}{1}) % Check if points exist for object
            %         app.handles(i) = plot3(app.UIAxes, ...
            %             app.positions{i}{1}(1, :), ...
            %             app.positions{i}{1}(2, :), ...
            %             app.positions{i}{1}(3, :), '.', 'DisplayName', app.objectNames{i});
            %     end
            % end
            hold(app.UIAxes, 'off');  % Release the hold

            % Set up the legend and axis labels
            app.DisplayTImestepLabel.Text = 'Timestep: 1';
            lgd = legend(app.UIAxes, 'show');
            lgd.FontSize = 16;

            % Ensure 3D view
            view(app.UIAxes, 3);  % Set 3D view for the axes

            [xLimits, yLimits, zLimits] = computeAxisLimits(app);

            % Set axis limits if needed
            axis(app.UIAxes, 'equal');  % Ensure equal scaling of axes
            xlim(app.UIAxes, xLimits);
            ylim(app.UIAxes, yLimits);
            zlim(app.UIAxes, zLimits);

            % Configure slider
            app.TimestepSlider.Limits = [1, app.numTimesteps];
            app.TimestepSlider.Value = 1;
            app.TimestepSlider.MajorTicks = 1:app.numTimesteps;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Prompt user to select data directory
            app.dataDir = uigetdir('', 'Select Data Directory');
            if isequal(app.dataDir, 0)
                uialert(app.UIFigure, 'No directory selected', 'Error');
                return; % Exit startup if no directory is selected
            end
        
            % Load data and detect available objects
            loadData(app);
        
            if app.numObjects == 0
                uialert(app.UIFigure, 'No objects found in the data.', 'Error');
                return;
            end
        
            %% Combined Selection Dialog
            selectionFig = uifigure('Name', 'Select Objects and Timesteps', 'Position', [500 300 400 500]);
        
            % Title for Object Selection
            uilabel(selectionFig, ...
                'Text', 'Select Objects to Display:', ...
                'Position', [20, 450, 200, 22], ...
                'FontWeight', 'bold');
        
            % Create checkboxes for each object
            checkboxes = gobjects(1, app.numObjects);
            for i = 1:app.numObjects
                checkboxes(i) = uicheckbox(selectionFig, ...
                                           'Text', app.objectNames{i}, ...
                                           'Position', [20, 430 - (i * 30), 200, 22], ...
                                           'Value', true); % Default is selected
            end
        
            % Separator
            uilabel(selectionFig, ...
                'Text', '_________________________________________', ...
                'Position', [20, 150, 360, 22]);
        
            % Title for Timestep Range Selection
            uilabel(selectionFig, ...
                'Text', 'Enter Timestep Range:', ...
                'Position', [20, 120, 200, 22], ...
                'FontWeight', 'bold');
        
            % Timestep Range Input
            startLabel = uilabel(selectionFig, ...
                'Text', 'Start Timestep:', ...
                'Position', [20, 90, 100, 22]);
            startField = uieditfield(selectionFig, 'numeric', ...
                'Value', 1, ...
                'Limits', [1, app.numTimesteps], ...
                'Position', [130, 90, 60, 22]);
        
            endLabel = uilabel(selectionFig, ...
                'Text', 'End Timestep:', ...
                'Position', [20, 60, 100, 22]);
            endField = uieditfield(selectionFig, 'numeric', ...
                'Value', app.numTimesteps, ...
                'Limits', [1, app.numTimesteps], ...
                'Position', [130, 60, 60, 22]);
        
            % Add OK Button
            okButton = uibutton(selectionFig, ...
                'Text', 'OK', ...
                'Position', [280, 20, 100, 30], ...
                'ButtonPushedFcn', @(btn, event) confirmSelection());
        
            % Pause execution until user confirms
            uiwait(selectionFig);
        
            %% Callback to Finalize Selection
            function confirmSelection()
                % Validate Object Selection
                selectedObjects = arrayfun(@(cb) cb.Value, checkboxes);
                if ~any(selectedObjects)
                    uialert(selectionFig, 'Please select at least one object.', 'Error');
                    return;
                end
        
                % Validate Timestep Range
                startTimestep = startField.Value;
                endTimestep = endField.Value;
        
                if startTimestep > endTimestep
                    uialert(selectionFig, 'Start timestep must be less than or equal to the end timestep.', 'Error');
                    return;
                end
        
                % Update app properties
                app.objectNames = app.objectNames(selectedObjects);
                app.positions = app.positions(selectedObjects);
                app.numObjects = sum(selectedObjects);
        
                % Trim data to the selected timestep range
                app.numTimesteps = endTimestep - startTimestep + 1;
                app.positions = cellfun(@(pos) pos(startTimestep:endTimestep), app.positions, 'UniformOutput', false);
        
                % Resume execution and close dialog
                uiresume(selectionFig);
                delete(selectionFig);
            end
        
            %% Initialize the 3D Plot
            initializePlot(app);
        end

        % Value changed function: TimestepSlider
        function TimestepSliderValueChanged(app, event)
            value = app.TimestepSlider.Value;
            
        end

        % Value changing function: TimestepSlider
        function TimestepSliderValueChanging(app, event)
            timeIndex = round(event.Value);

            % Skip update if timestep hasn't changed
            persistent lastTimeIndex;
            if ~isempty(lastTimeIndex) && lastTimeIndex == timeIndex
                return;
            end
            lastTimeIndex = timeIndex;
        
            % Update only objects with valid data
            for i = 1:app.numObjects
                if ~isempty(app.positions{i}{timeIndex}) && isgraphics(app.handles(i))
                    currentData = app.positions{i}{timeIndex};
                    % Only update if data differs
                    if ~isequal(get(app.handles(i), 'XData'), currentData(1, :))
                        set(app.handles(i), 'XData', currentData(1, :), ...
                                            'YData', currentData(2, :), ...
                                            'ZData', currentData(3, :));
                    end
                end
            end
        
            % Update label
            app.DisplayTImestepLabel.Text = sprintf('Timestep: %d', timeIndex);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            SaveAllFramesButtonPushed(app, event);
            function SaveAllFramesButtonPushed(app, ~)
                % Prompt the user to select a folder for saving the screenshots
                saveFolder = uigetdir('', 'Select Folder to Save Frames');
                if isequal(saveFolder, 0)
                    return; % User canceled
                end
            
                % Create a progress dialog
                progressDlg = uiprogressdlg(app.UIFigure, ...
                    'Title', 'Saving Frames', ...
                    'Message', 'Initializing...', ...
                    'Cancelable', 'on');
            
                % Loop through each timestep
                try
                    for t = 1:app.numTimesteps
                        
                        % Bring the app back to focus explicitly
                        figure(app.UIFigure);

                        % Check if the user has canceled
                        if progressDlg.CancelRequested
                            error('Frame saving canceled by user.');
                        end
            
                        % Update progress dialog
                        progressDlg.Value = t / app.numTimesteps;
                        progressDlg.Message = sprintf('Saving frame %d of %d...', t, app.numTimesteps);
            
                        % Update plots
                        updatePlots(app, t);
            
                        % Generate a file name (e.g., "frame_0001.png")
                        fileName = sprintf('frame_%04d.png', t);
            
                        % Save the plot from the UIAxes
                        exportgraphics(app.UIAxes, fullfile(saveFolder, fileName));
                    end
            
                    % Notify the user of success
                    progressDlg.Message = 'All frames saved successfully.';
                    pause(1); % Allow the user to see the completion message
            
                catch ME
                    % Handle cancellation or errors
                    if ~strcmp(ME.message, 'Frame saving canceled by user.')
                        uialert(app.UIFigure, sprintf('Error:\n%s', ME.message), 'Error');
                    end
                end
            
                % Close the progress dialog
                close(progressDlg);
            end
            
            function updatePlots(app, timeIndex)
                % Efficiently update plots without triggering unnecessary events
                for i = 1:app.numObjects
                    if ~isempty(app.positions{i}{timeIndex}) && isgraphics(app.handles(i))
                        set(app.handles(i), ...
                            'XData', app.positions{i}{timeIndex}(1, :), ...
                            'YData', app.positions{i}{timeIndex}(2, :), ...
                            'ZData', app.positions{i}{timeIndex}(3, :));
                    end
                end
                % Update timestep label
                app.DisplayTImestepLabel.Text = sprintf('Timestep: %d', timeIndex);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TimestepSliderLabel = uilabel(app.UIFigure);
            app.TimestepSliderLabel.Text = 'Timestep';
            app.TimestepSlider = uislider(app.UIFigure);
            app.TimestepSlider.ValueChangedFcn = createCallbackFcn(app, @TimestepSliderValueChanged, true);
            app.TimestepSlider.ValueChangingFcn = createCallbackFcn(app, @TimestepSliderValueChanging, true);
            app.DisplayTImestepLabel = uilabel(app.UIFigure);
            app.DisplayTImestepLabel.Text = 'DisplayTImestep';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MotionTrackingDisplay_ITCL_v04

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end